classdef productos_25 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        tabla                         matlab.ui.control.Table
        ANULARButton                  matlab.ui.control.Button
        ELIMINARButton                matlab.ui.control.Button
        MODIFICARButton               matlab.ui.control.Button
        GUARDARButton                 matlab.ui.control.Button
        txt3                          matlab.ui.control.NumericEditField
        PrecioUnitarioEditFieldLabel  matlab.ui.control.Label
        txt2                          matlab.ui.control.NumericEditField
        PrecioCostoEditFieldLabel     matlab.ui.control.Label
        txt1                          matlab.ui.control.NumericEditField
        CantidadEditFieldLabel        matlab.ui.control.Label
        SERIEIIButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        idProdG % Variable Global del Id del Producto seleccionado en la tabla para modificar
    end

    methods (Access = private)
       
        function funcionLimpiar(app)
            app.tabla.Data = consultaDato;
            app.txt1.Value = 0;
            app.txt2.Value = 0;
            app.txt3.Value = 0;
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MODIFICARButton
        function MODIFICARButtonPushed(app, event)
            can = app.txt1.Value;
            precCos = app.txt2.Value;
            precUn = app.txt3.Value;
            modificarDato(app.idProdG, can, precCos, precUn)
            msgbox("Dato Motificado!")
            app.funcionLimpiar
        end

        % Button pushed function: ELIMINARButton
        function ELIMINARButtonPushed(app, event)
            eliminarDato(app.idProdG)
            funcionLimpiar(app)
            msgbox("Registro Eliminado!")
        end

        % Button pushed function: ANULARButton
        function ANULARButtonPushed(app, event)
            anularDato(app.idProdG)
            funcionLimpiar(app)
            msgbox("Registro Anulado!")
        end

        % Selection changed function: tabla
        function tablaSelectionChanged(app, event)
            selection = app.tabla.Selection;
            if ~isempty(selection)
                selectRow = selection(1);
                selecId = app.tabla.Data{selectRow, 1};
                can = app.tabla.Data{selectRow, 2};
                precCos = app.tabla.Data{selectRow, 3};
                precUn = app.tabla.Data{selectRow, 4};
                app.txt1.Value = can;
                app.txt2.Value = precCos;
                app.txt3.Value = precUn;
                app.idProdG = selecId;
            end
        end

        % Button pushed function: GUARDARButton
        function GUARDARButtonPushed(app, event)
             can = app.txt1.Value;
            precCos = app.txt2.Value;
            precUn = app.txt3.Value;
            insertarDato(can, precCos, precUn)
            msgbox("Dato Guardado!")
            app.funcionLimpiar
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SERIEIIButton = uibutton(app.UIFigure, 'push');
            app.SERIEIIButton.Text = 'SERIE II';
            app.CantidadEditFieldLabel = uilabel(app.UIFigure);
            app.CantidadEditFieldLabel.Text = 'Cantidad';
            app.txt1 = uieditfield(app.UIFigure, 'numeric');
            app.PrecioCostoEditFieldLabel = uilabel(app.UIFigure);
            app.PrecioCostoEditFieldLabel.Text = 'Precio Costo';
            app.txt2 = uieditfield(app.UIFigure, 'numeric');
            app.PrecioUnitarioEditFieldLabel = uilabel(app.UIFigure);
            app.PrecioUnitarioEditFieldLabel.Text = 'Precio Unitario';
            app.txt3 = uieditfield(app.UIFigure, 'numeric');
            app.GUARDARButton = uibutton(app.UIFigure, 'push');
            app.GUARDARButton.ButtonPushedFcn = createCallbackFcn(app, @GUARDARButtonPushed, true);
            app.GUARDARButton.Text = 'GUARDAR';
            app.MODIFICARButton = uibutton(app.UIFigure, 'push');
            app.MODIFICARButton.ButtonPushedFcn = createCallbackFcn(app, @MODIFICARButtonPushed, true);
            app.MODIFICARButton.Text = 'MODIFICAR';
            app.ELIMINARButton = uibutton(app.UIFigure, 'push');
            app.ELIMINARButton.ButtonPushedFcn = createCallbackFcn(app, @ELIMINARButtonPushed, true);
            app.ELIMINARButton.Text = 'ELIMINAR';
            app.ANULARButton = uibutton(app.UIFigure, 'push');
            app.ANULARButton.ButtonPushedFcn = createCallbackFcn(app, @ANULARButtonPushed, true);
            app.ANULARButton.Text = 'ANULAR';
            app.tabla = uitable(app.UIFigure);
            app.tabla.ColumnName = {'ID_PRODUCTO_25'; 'CANTIDAD_25'; 'PRECIO_COSTO_25'; 'PRECIO_UNITARIO_25'};
            app.tabla.RowName = {};
            app.tabla.SelectionChangedFcn = createCallbackFcn(app, @tablaSelectionChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = productos_25

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end