classdef productos_38 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        table                       matlab.ui.control.Table
        PrecioUnidadEditField       matlab.ui.control.NumericEditField
        PrecioUnidadEditFieldLabel  matlab.ui.control.Label
        PrecioCostoEditField        matlab.ui.control.NumericEditField
        PrecioCostoEditFieldLabel   matlab.ui.control.Label
        CantidadEditField           matlab.ui.control.NumericEditField
        CantidadEditFieldLabel      matlab.ui.control.Label
        AnularButton                matlab.ui.control.Button
        EliminarButton              matlab.ui.control.Button
        ModificarButton             matlab.ui.control.Button
        GuardarButton               matlab.ui.control.Button
        PRODUCTOS_38Label           matlab.ui.control.Label
    end

    
    properties (Access = private)
        idProdG % Variable Global del Id del Producto seleccionado en la tabla para modificar
    end
    
    methods (Access = private)
        
        function funcionLimpiar(app)
            app.table.Data = consultaDato;
            app.CantidadEditField.Value = 0;
            app.PrecioCostoEditField.Value = 0;
            app.PrecioUnidadEditField.Value = 0;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.table.Data = consultaDato;
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            can = app.CantidadEditField.Value;
            precCos = app.PrecioCostoEditField.Value;
            precUn = app.PrecioUnidadEditField.Value;
            insertarDato(can, precCos, precUn)
            msgbox("Dato Guardado!")
            app.funcionLimpiar
        end

        % Button pushed function: ModificarButton
        function ModificarButtonPushed(app, event)
            can = app.CantidadEditField.Value;
            precCos = app.PrecioCostoEditField.Value;
            precUn = app.PrecioUnidadEditField.Value;
            modificarDato(app.idProdG, can, precCos, precUn)
            msgbox("Dato Motificado!")
            app.funcionLimpiar
        end

        % Button pushed function: EliminarButton
        function EliminarButtonPushed(app, event)
            eliminarDato(app.idProdG)
            funcionLimpiar(app)
            msgbox("Registro Eliminado!")
        end

        % Button pushed function: AnularButton
        function AnularButtonPushed(app, event)
            anularDato(app.idProdG)
            funcionLimpiar(app)
            msgbox("Registro Anulado!")
        end

        % Selection changed function: table
        function tableSelectionChanged(app, event)
            selection = app.table.Selection;
            if ~isempty(selection)
                selectRow = selection(1);
                selecId = app.table.Data{selectRow, 1};
                can = app.table.Data{selectRow, 2};
                precCos = app.table.Data{selectRow, 3};
                precUn = app.table.Data{selectRow, 4};
                app.CantidadEditField.Value = can;
                app.PrecioCostoEditField.Value = precCos;
                app.PrecioUnidadEditField.Value = precUn;
                app.idProdG = selecId;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PRODUCTOS_38Label = uilabel(app.UIFigure);
            app.PRODUCTOS_38Label.Text = 'PRODUCTOS_38';
            app.GuardarButton = uibutton(app.UIFigure, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.ModificarButton = uibutton(app.UIFigure, 'push');
            app.ModificarButton.ButtonPushedFcn = createCallbackFcn(app, @ModificarButtonPushed, true);
            app.ModificarButton.Text = 'Modificar';
            app.EliminarButton = uibutton(app.UIFigure, 'push');
            app.EliminarButton.ButtonPushedFcn = createCallbackFcn(app, @EliminarButtonPushed, true);
            app.EliminarButton.Text = 'Eliminar';
            app.AnularButton = uibutton(app.UIFigure, 'push');
            app.AnularButton.ButtonPushedFcn = createCallbackFcn(app, @AnularButtonPushed, true);
            app.AnularButton.Text = 'Anular';
            app.CantidadEditFieldLabel = uilabel(app.UIFigure);
            app.CantidadEditFieldLabel.Text = 'Cantidad';
            app.CantidadEditField = uieditfield(app.UIFigure, 'numeric');
            app.PrecioCostoEditFieldLabel = uilabel(app.UIFigure);
            app.PrecioCostoEditFieldLabel.Text = 'Precio Costo';
            app.PrecioCostoEditField = uieditfield(app.UIFigure, 'numeric');
            app.PrecioUnidadEditFieldLabel = uilabel(app.UIFigure);
            app.PrecioUnidadEditFieldLabel.Text = 'Precio Unidad';
            app.PrecioUnidadEditField = uieditfield(app.UIFigure, 'numeric');
            app.table = uitable(app.UIFigure);
            app.table.ColumnName = {'ID_PRODUCTO_38'; 'CANTIDAD_38'; 'PRECIO_COSTO_38'; 'PRECIO_UNITARIO_38'};
            app.table.RowName = {};
            app.table.SelectionChangedFcn = createCallbackFcn(app, @tableSelectionChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = productos_38

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end